classdef pccut_gui_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PlaneNormalVectorLabel        matlab.ui.control.Label
        PlanePointLabel               matlab.ui.control.Label
        SavePointCloudButton          matlab.ui.control.Button
        OriginalViewButton            matlab.ui.control.StateButton
        xSliderLabel                  matlab.ui.control.Label
        xSlider                       matlab.ui.control.Slider
        ySliderLabel                  matlab.ui.control.Label
        ySlider                       matlab.ui.control.Slider
        zSliderLabel                  matlab.ui.control.Label
        zSlider                       matlab.ui.control.Slider
        ASliderLabel                  matlab.ui.control.Label
        ASlider                       matlab.ui.control.Slider
        BSliderLabel                  matlab.ui.control.Label
        BSlider                       matlab.ui.control.Slider
        CSliderLabel                  matlab.ui.control.Label
        CSlider                       matlab.ui.control.Slider
        showarrowCheckBox             matlab.ui.control.CheckBox
        LoadPointCloudButton          matlab.ui.control.Button
        DepthEditFieldLabel           matlab.ui.control.Label
        DepthEditField                matlab.ui.control.NumericEditField
        PoissonReconstructionLabel    matlab.ui.control.Label
        SamplesPerNodeEditFieldLabel  matlab.ui.control.Label
        SamplesPerNodeEditField       matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function show_pc(app)
            global A;
            global B;
            global C;
            global x;
            global y;
            global z;            
            global ptCloud;     
            global ax;
            global ax_view;
            
            ax_view = get(ax,'View');
            show_arrow = app.showarrowCheckBox.Value;
            if(app.OriginalViewButton.Value)
                show_point_cloud(ax,ptCloud,A,B,C,x,y,z, show_arrow);    
            else
                pclinearcutinliers(ax,ptCloud,A,B,C,x,y,z);
            end
            view(ax, ax_view);
        end
        
        function set_new_pc(app)  
            global A;
            global B;
            global C;
            global x;
            global y;
            global z;            
            global ptCloud; 
            global pcPath;
            global pcFile;
            
            h = msgbox('Loading...');

            ptCloud = pcread(strcat(pcPath,pcFile));
            ptCloud.XLimits
            app.xSlider.Limits = double(ptCloud.XLimits);
            app.ySlider.Limits = double(ptCloud.YLimits);
            app.zSlider.Limits = double(ptCloud.ZLimits);
            
            X = ptCloud.Location;
            covarianceMatrix = cov(X);
            [V,~] = eig(covarianceMatrix);
            
            V1 = V(:,3);
            center = mean(X,1);
                        
            A = V1(1);
            B = V1(2);
            C = V1(3);
            x= center(1);
            y = center(2);
            z = center(3);
            
            app.ASlider.Value = double(V1(1));
            app.BSlider.Value = double(V1(2));
            app.CSlider.Value = double(V1(3));
            app.xSlider.Value = double(center(1));
            app.ySlider.Value = double(center(2));
            app.zSlider.Value = double(center(3));
            
            show_pc(app);
            
            delete(h);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global A;
            global B;
            global C;
            global x;
            global y;
            global z;       
            global f;  
            global ax;
            global prePC;
            
            A=1;
            B=0;
            C=0;
            x=0;
            y=0;
            z=0;
            prePC = {};
            
            screensize = get( groot, 'Screensize' );
            x_size = screensize(3);
            y_size = screensize(4);
            f = figure;
            f.OuterPosition = [round(x_size/2), 0, round(x_size/2), y_size];
            app.UIFigure.Position=[1,round(y_size/8),round(x_size/2), round(y_size*3/4)];
            ax= gca;
            view(ax,3);
            set(app.SavePointCloudButton,'Enable','off');
        end

        % Callback function
        function LoadPointCloudButtonPushed(app, event)
            global ptCloud;  

            ptCloudName = app.PointCloudVariableNameEditField.Value;
            ptCloudCellName = "";
            ptCloudNum = "0";
            if(contains(ptCloudName,'{') && contains(ptCloudName,'}'))
                ptCloudCellName = split(ptCloudName,'{');
                ptCloudNum = ptCloudCellName{2};
                ptCloudNum = ptCloudNum(1:end-1);
                ptCloudCellName = ptCloudCellName{1};
            end
            
            ise = evalin( 'base', strcat("exist('",string(ptCloudName),"','var') == 1"));
            is_cell = evalin( 'base', strcat("exist('",string(ptCloudCellName),"','var') == 1"));
            if ((~ise && ~is_cell) ||(is_cell && (str2num(ptCloudNum)< 1)))
                msgbox(strcat("Variable named ", ptCloudName, " doesn't exist"),...
                    'Error Loading Point Cloud','error');
                
                
            else
                out_of_index = false;
                if(is_cell)
                    pc_cell = evalin('base',ptCloudCellName);
                    if(str2num(ptCloudNum) > length(pc_cell))
                        out_of_index = true;
                    end
                end
                if(out_of_index)
                        msgbox(strcat("Index exceeds cell length"),...
                            'Error Loading Point Cloud','error');
                else
                    pc = evalin('base',ptCloudName);
                    if ~isa(pc,'pointCloud')
                        msgbox(strcat(ptCloudName, " is not a point cloud"),...
                            'Error Loading Point Cloud','error');
                        
                    else
                        h = msgbox('Loading...');
                        prePC = {};
                        ptCloud = pc;
                        app.xSlider.Limits = ptCloud.XLimits;
                        app.ySlider.Limits = ptCloud.YLimits;
                        app.zSlider.Limits = ptCloud.ZLimits;
                        set_new_pc(app);
                        delete(h);
                    end 
                end
            end


        end

        % Callback function
        function AEditFieldValueChanged(app, event)
            global A;
            
            A = app.AEditField.Value;
    
            show_pc(app);            
        end

        % Callback function
        function BEditFieldValueChanged(app, event)
            global B;
            
            B = app.BEditField.Value;
    
            show_pc(app);
            
        end

        % Callback function
        function CEditFieldValueChanged(app, event)
            global C;
                        
            C = app.CEditField.Value;
    
            show_pc(app);
             
            
        end

        % Callback function
        function xEditFieldValueChanged(app, event)
            global x;
            
            x = app.xEditField.Value;
    
            show_pc(app);
        end

        % Callback function
        function yEditFieldValueChanged(app, event)
            global y;
            
            y = app.yEditField.Value;
    
            show_pc(app);
        end

        % Callback function
        function zEditFieldValueChanged(app, event)
            global z;            
         
            z = app.zEditField.Value;
    
            show_pc(app);
        end

        % Button pushed function: SavePointCloudButton
        function SavePointCloudButtonPushed(app, event)
            global A;
            global B;
            global C;
            global x;
            global y;
            global z;            
            global ptCloud;     
            global ax;
            global pcPath;
            global pcFile;
            
            
            h = msgbox('Saving...');
            pccut = pclinearcutinliers(ax,ptCloud,A,B,C,x,y,z);
            
            pc_full_path = strcat(pcPath, pcFile);
            cut_ply_path = strrep(pc_full_path, '.ply', '_cut.ply');
            cut_mat_path = strrep(pc_full_path, '.ply', '_cut.mat');
            cut_mesh_path = strrep(pc_full_path, '.ply', '_cut_recon.ply');
            
            pcwrite(pccut, cut_ply_path);
            poisson_surface_reconstruction(cut_ply_path,cut_mesh_path,...
            app.DepthEditField.Value, app.SamplesPerNodeEditField.Value);
            
            X = pccut.Location;
            save(cut_mat_path, 'X');
            delete(h);
            figure;
            pcshow(pccut);
            title('Output Point Cloud')
            xlabel('X(m)');
            ylabel('Y(m)');
            zlabel('Z(m)');
        end

        % Callback function
        function ApplyRadiusFilterButtonPushed(app, event)
      
            global ptCloud;  
            global prePC;
            
            prePC = {prePC{:},ptCloud};
            
            r = app.REditField.Value;
            nnn = app.NNNEditField.Value;
            
            h = msgbox('Applying Radius Filter...');
            
            ptCloud = pcradiusfilter(ptCloud, r, nnn);
            delete(h);
    
            show_pc(app);
        end

        % Callback function
        function ApplyMedianFilterButtonPushed(app, event)
            global ptCloud;    
            global prePC;
            
            prePC = {prePC{:},ptCloud};
            
            k = app.KEditField.Value;
            win_size = app.WindowSizeEditField.Value;
            
            h = msgbox('Applying Median Filter...');
            
            ptCloud = pcfilter(ptCloud, 'movmedian', win_size,k);

            delete(h);
    
            show_pc(app);
        end

        % Value changed function: OriginalViewButton
        function OriginalViewButtonValueChanged(app, event)
          show_pc(app);
        end

        % Callback function
        function UndoFilteringButtonPushed(app, event)
            global ptCloud;    

            global prePC;
            spre = size(prePC);
            spre = spre(2);
            if (spre == 0)
                
                msgbox('No filters were appllied','Error','error');
                
            else
                
                h = msgbox('Undoing Filtering...');
                ptCloud = prePC{end};
                prePC = prePC(1:end-1);
                show_pc(app);
                delete(h);
                
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            h = msgbox('Closing app...');
            pause(1);
            global f;
            delete(f);
            delete(h);
            delete(app)
            
        end

        % Value changing function: xSlider
        function xSliderValueChanging(app, event)
            changingValue = event.Value;
            global x;
            
            x = changingValue;
    
            show_pc(app);
        end

        % Value changing function: ySlider
        function ySliderValueChanging(app, event)
            changingValue = event.Value;
            global y;
            
            y = changingValue;
    
            show_pc(app);
        end

        % Value changing function: zSlider
        function zSliderValueChanging(app, event)
            changingValue = event.Value;
            global z;
            
            z = changingValue;
    
            show_pc(app);
        end

        % Value changing function: ASlider
        function ASliderValueChanging(app, event)
            changingValue = event.Value;
            global A;
            
            A = changingValue;
    
            show_pc(app);
        end

        % Value changing function: BSlider
        function BSliderValueChanging(app, event)
            changingValue = event.Value;
            global B;
            
            B = changingValue;
    
            show_pc(app);
        end

        % Value changing function: CSlider
        function CSliderValueChanging(app, event)
            changingValue = event.Value;
            global C;
            
            C = changingValue;
    
            show_pc(app);
        end

        % Value changed function: showarrowCheckBox
        function showarrowCheckBoxValueChanged(app, event)
            show_pc(app);
        end

        % Callback function
        function EigenVectorDropDownValueChanged(app, event)
            set_new_pc(app);            
        end

        % Callback function
        function AvailablesPointCloudsDropDownValueChanged(app, event)
            set_new_pc(app);      
        end

        % Button pushed function: LoadPointCloudButton
        function LoadPointCloudButtonPushed2(app, event)
            global pcPath;
            global pcFile;
            
            [file,path] = uigetfile('final.ply', 'Select a Point Cloud');
            if(~isequal(file,0))                
                pcFile = file;
                pcPath = path;
                set(app.SavePointCloudButton,'Enable','on');
                set_new_pc(app);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PlaneNormalVectorLabel = uilabel(app.UIFigure);
            app.PlaneNormalVectorLabel.Text = 'Plane - Normal Vector';
            app.PlanePointLabel = uilabel(app.UIFigure);
            app.PlanePointLabel.Text = 'Plane - Point';
            app.SavePointCloudButton = uibutton(app.UIFigure, 'push');
            app.SavePointCloudButton.ButtonPushedFcn = createCallbackFcn(app, @SavePointCloudButtonPushed, true);
            app.SavePointCloudButton.Text = 'Save Point Cloud';
            app.OriginalViewButton = uibutton(app.UIFigure, 'state');
            app.OriginalViewButton.ValueChangedFcn = createCallbackFcn(app, @OriginalViewButtonValueChanged, true);
            app.OriginalViewButton.Text = 'Original View';
            app.OriginalViewButton.Value = true;
            app.xSliderLabel = uilabel(app.UIFigure);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.UIFigure);
            app.xSlider.Limits = [-1 1];
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.ySliderLabel = uilabel(app.UIFigure);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.UIFigure);
            app.ySlider.Limits = [-1 1];
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.zSliderLabel = uilabel(app.UIFigure);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.UIFigure);
            app.zSlider.Limits = [-1 1];
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @zSliderValueChanging, true);
            app.ASliderLabel = uilabel(app.UIFigure);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.UIFigure);
            app.ASlider.Limits = [-1 1];
            app.ASlider.ValueChangingFcn = createCallbackFcn(app, @ASliderValueChanging, true);
            app.BSliderLabel = uilabel(app.UIFigure);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.UIFigure);
            app.BSlider.Limits = [-1 1];
            app.BSlider.ValueChangingFcn = createCallbackFcn(app, @BSliderValueChanging, true);
            app.CSliderLabel = uilabel(app.UIFigure);
            app.CSliderLabel.Text = 'C';
            app.CSlider = uislider(app.UIFigure);
            app.CSlider.Limits = [-1 1];
            app.CSlider.ValueChangingFcn = createCallbackFcn(app, @CSliderValueChanging, true);
            app.showarrowCheckBox = uicheckbox(app.UIFigure);
            app.showarrowCheckBox.ValueChangedFcn = createCallbackFcn(app, @showarrowCheckBoxValueChanged, true);
            app.showarrowCheckBox.Text = 'show arrow';
            app.LoadPointCloudButton = uibutton(app.UIFigure, 'push');
            app.LoadPointCloudButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPointCloudButtonPushed2, true);
            app.LoadPointCloudButton.Text = 'Load Point Cloud';
            app.DepthEditFieldLabel = uilabel(app.UIFigure);
            app.DepthEditFieldLabel.Text = 'Depth';
            app.DepthEditField = uieditfield(app.UIFigure, 'numeric');
            app.DepthEditField.Value = 5;
            app.PoissonReconstructionLabel = uilabel(app.UIFigure);
            app.PoissonReconstructionLabel.Text = 'Poisson Reconstruction';
            app.SamplesPerNodeEditFieldLabel = uilabel(app.UIFigure);
            app.SamplesPerNodeEditFieldLabel.Text = 'Samples Per Node';
            app.SamplesPerNodeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplesPerNodeEditField.Value = 20;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pccut_gui_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end