classdef run_algorithm_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CalibrationTab                  matlab.ui.container.Tab
        RadiusErrorPercentageEditFieldLabel  matlab.ui.control.Label
        RadiusErrorPercentageEditField  matlab.ui.control.NumericEditField
        MaxNumTrialsEditFieldLabel      matlab.ui.control.Label
        MaxNumTrialsEditField           matlab.ui.control.NumericEditField
        numOfTriesEditFieldLabel        matlab.ui.control.Label
        numOfTriesEditField             matlab.ui.control.NumericEditField
        RunNewCalibrationCheckBox       matlab.ui.control.CheckBox
        DrawCentroidsCheckBox           matlab.ui.control.CheckBox
        MaxDistanceEditFieldLabel       matlab.ui.control.Label
        MaxDistanceEditField            matlab.ui.control.NumericEditField
        CalibrationParametersButton     matlab.ui.control.Button
        RunCalibrationButton            matlab.ui.control.Button
        AlignmentTab                    matlab.ui.container.Tab
        GridStepEditFieldLabel          matlab.ui.control.Label
        GridStepEditField               matlab.ui.control.NumericEditField
        NewAlignmentCheckBox            matlab.ui.control.CheckBox
        AddNormalsCheckBox              matlab.ui.control.CheckBox
        KnormalsEditFieldLabel          matlab.ui.control.Label
        KnormalsEditField               matlab.ui.control.NumericEditField
        MinDistanceEditFieldLabel       matlab.ui.control.Label
        MinDistanceEditField            matlab.ui.control.NumericEditField
        TrimPointCloudCheckBox          matlab.ui.control.CheckBox
        AlignmentParametersButton       matlab.ui.control.Button
        RunAlignmentButton              matlab.ui.control.Button
        DenoisingTab                    matlab.ui.container.Tab
        NeighborsFilterRadiusEditFieldLabel  matlab.ui.control.Label
        NeighborsFilterRadiusEditField  matlab.ui.control.NumericEditField
        NeighborsFilterNoisePercentageEditFieldLabel  matlab.ui.control.Label
        NeighborsFilterNoisePercentageEditField  matlab.ui.control.NumericEditField
        CleanBeforeRegisterationCheckBox  matlab.ui.control.CheckBox
        CleanAfterRegisterationCheckBox  matlab.ui.control.CheckBox
        GuidedFilterRadiusEditFieldLabel  matlab.ui.control.Label
        GuidedFilterRadiusEditField     matlab.ui.control.NumericEditField
        GuidedFilterRegularizationEditFieldLabel  matlab.ui.control.Label
        GuidedFilterRegularizationEditField  matlab.ui.control.NumericEditField
        BoundryFilterRadiusEditFieldLabel  matlab.ui.control.Label
        BoundryFilterRadiusEditField    matlab.ui.control.NumericEditField
        BoundryFilterThresholdEditFieldLabel  matlab.ui.control.Label
        BoundryFilterThresholdEditField  matlab.ui.control.NumericEditField
        DenoisingParametersButton       matlab.ui.control.Button
        RunDenoisingBeforeButton        matlab.ui.control.Button
        RunDenoisingAfterButton         matlab.ui.control.Button
        RegistrationTab                 matlab.ui.container.Tab
        MinErrorThresholdEditFieldLabel  matlab.ui.control.Label
        MinErrorThresholdEditField      matlab.ui.control.NumericEditField
        NewRegistrationCheckBox         matlab.ui.control.CheckBox
        GridStepEditField_2Label        matlab.ui.control.Label
        GridStepEditField_2             matlab.ui.control.NumericEditField
        MaxErrorThresholdEditFieldLabel  matlab.ui.control.Label
        MaxErrorThresholdEditField      matlab.ui.control.NumericEditField
        JumpErrorThresholdEditFieldLabel  matlab.ui.control.Label
        JumpErrorThresholdEditField     matlab.ui.control.NumericEditField
        MinSuccessfulRegisterationsEditFieldLabel  matlab.ui.control.Label
        MinSuccessfulRegisterationsEditField  matlab.ui.control.NumericEditField
        InliersRatioEditFieldLabel      matlab.ui.control.Label
        InliersRatioEditField           matlab.ui.control.NumericEditField
        RegistrationParametersButton    matlab.ui.control.Button
        RunRegistrationButton           matlab.ui.control.Button
        SelectPointCloudsCheckBox       matlab.ui.control.CheckBox
        ReconstructionTab               matlab.ui.container.Tab
        PoissonDepthEditFieldLabel      matlab.ui.control.Label
        PoissonDepthEditField           matlab.ui.control.NumericEditField
        PoissonSamplesPerNodeEditFieldLabel  matlab.ui.control.Label
        PoissonSamplesPerNodeEditField  matlab.ui.control.NumericEditField
        NewReconstructionCheckBox       matlab.ui.control.CheckBox
        ReconstructionParametersButton  matlab.ui.control.Button
        RunReconstructionButton         matlab.ui.control.Button
        SelectDirectoryButton           matlab.ui.control.StateButton
        RunAlgorithmButton              matlab.ui.control.Button
        SpherecircumferencecmEditFieldLabel  matlab.ui.control.Label
        SpherecircumferencecmEditField  matlab.ui.control.NumericEditField
        ContainingDirectoryNameEditFieldLabel  matlab.ui.control.Label
        ContainingDirectoryNameEditField  matlab.ui.control.EditField
        PrintingOutputTextAreaLabel     matlab.ui.control.Label
        PrintingOutputTextArea          matlab.ui.control.TextArea
        ModeButtonGroup                 matlab.ui.container.ButtonGroup
        RegularButton                   matlab.ui.control.ToggleButton
        AdvancedButton                  matlab.ui.control.ToggleButton
        ExpertButton                    matlab.ui.control.ToggleButton
        SetAllValuestoDefaultButton     matlab.ui.control.Button
        AvailableCapturesFoldersListBoxLabel  matlab.ui.control.Label
        AvailableCapturesFoldersListBox  matlab.ui.control.ListBox
        RunningLamp                     matlab.ui.control.Lamp
        ClearButton                     matlab.ui.control.Button
        HELPButton                      matlab.ui.control.Button
        STOPButton                      matlab.ui.control.StateButton
        AvailableCalibrationFoldersDropDownLabel  matlab.ui.control.Label
        AvailableCalibrationFoldersDropDown  matlab.ui.control.DropDown
        PointCloudsListLabel            matlab.ui.control.Label
        PointCloudsListListBox          matlab.ui.control.ListBox
        SelectPointCloudsButton         matlab.ui.control.Button
        LoadPointCloudButton            matlab.ui.control.Button
        SelectAllButton                 matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function set_defaults(app)
                app.RunningLamp.Color = 'red';
                 
                app.RadiusErrorPercentageEditField.Value = 0.1;
                app.MaxNumTrialsEditField.Value = 1e6;
                app.numOfTriesEditField.Value = 20;
                app.MaxDistanceEditField.Value = 0.01;
                app.RunNewCalibrationCheckBox.Value = true;
                app.DrawCentroidsCheckBox.Value = false;
                
                app.GridStepEditField.Value = 5e-4;
                app.NewAlignmentCheckBox.Value = true;
                app.AddNormalsCheckBox.Value = false;
                app.KnormalsEditField.Value = 3;
                app.TrimPointCloudCheckBox.Value = true;
                app.MinDistanceEditField.Value = 0.02;
                
                app.NeighborsFilterRadiusEditField.Value = 1e-2;
                app.NeighborsFilterNoisePercentageEditField.Value = 1.5e-2;
                app.CleanBeforeRegisterationCheckBox.Value = true;
                app.CleanAfterRegisterationCheckBox.Value = true;
                app.GuidedFilterRadiusEditField.Value = 1e-2;
                app.GuidedFilterRegularizationEditField.Value = 1e-2;
                app.BoundryFilterRadiusEditField.Value = 1e-2;
                app.BoundryFilterThresholdEditField.Value = 5e-3;
                
                app.MinErrorThresholdEditField.Value = 5e-3;
                app.NewRegistrationCheckBox.Value = true;
                app.GridStepEditField_2.Value = 5e-3;
                app.MaxErrorThresholdEditField.Value = 6e-3;
                app.JumpErrorThresholdEditField.Value = 2e-4;
                app.MinSuccessfulRegisterationsEditField.Value = 2;
                app.InliersRatioEditField.Value = 0.95;
                app.SelectPointCloudsCheckBox.Value = true;
                
                app.NewReconstructionCheckBox.Value = true;
                app.PoissonDepthEditField.Value = 5;
                app.PoissonSamplesPerNodeEditField.Value = 20;
                
                app.PointCloudsListListBox.Enable = false;
                app.SelectAllButton.Enable = false;
                app.SelectPointCloudsButton.Enable = false;
                
        end
        
        function results = help(app)
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            
            help_text = {"\fontsize{10} Hello! This is the HELP message box." ,  ...
                "You can open it by pressing the HELP button or pressing the F1 key." , ...
                "Here are the instructions of using this GUI - " , "" , ...
                "First, select the containing directory. It must contain the calibration " + ...
                "folders, and all the captures folders you want to run the algorithm with." , ...
                "Second, enter the circumference (in cm) of the sphere you used for the calibration." , ...
                "Now you can select all the captures folders that you would like to run" , ...
                "the algorithm with, and one of the calibration folders. " + ...
                "If you want to choose numerous captures folders, " + ...
                "hold the Ctrl key, and select them. " + ...
                "In the top right corner of the GUI you can change the parameters " + ...
                "of the algorithm. These parameters are constant for every " + ...
                "run of the algorithm with each of the selected captures folders. " +  ...
                "You need to change the mode to " + ...
                "Advanced/Expert in order to change them. In Advanced mode, " + ...
                "some of the parameters cannot be changed (and most likely " + ...
                "don't need any change " + ...
                "from their default values). You can return " + ...
                "the parameters to their default values by pressing the reset " + ...
                "button. In every tab you have another help button, " + ...
                "which will open a new message box that is related to the tab, " + ...
                "with details about each parameter in the tab, and another " + ...
                "button for running this part only." ,...
                "After you've set the parameters, you can run the algorithm by pressing " + ...
                "the RUN ALGORITHM button. While the algorithm is running, " + ...
                "the lamp will show a green light and will turn back to red " + ...
                "when it will stop." ,...
                "In the output textbox, you'll see the output of the algorithm. " + ...
                "You can clear the text by pressing the CLEAR button." , ...
                "You can stop the run at any time by pressing the STOP button." + ...
                "The run will stop as soon as it can (keep the button on " + ...
                "hold until then). " , ...
                "On the right side of the GUI there is a section for selecting" + ...
                " point clouds for the registration. If the checkbox " + ...
                "for selecting point clouds (in the registration tab) is " + ...
                "checked, then you will need to check the point clouds that " + ...
                "you eant to register. There is also a button for loading a " + ...
                "point cloud that will be shown in the figure next to the GUI."};
            msgbox(help_text, 'HELP','help',CreateStruct);
        end
        
        function results = run_calibration(app)
            global full_path;
            calibration_dir = app.AvailableCalibrationFoldersDropDown.Value;
            
            if(isempty(calibration_dir))
                msgbox('Please select a calibration directory','Error','error');
                throw(MException());              
            end
            
            if(length(dir((calibration_dir))) == 2)
                msgbox('Calibration directory is empty','Error','error');
                throw(MException());              
            end
            
            if(app.SpherecircumferencecmEditField.Value <= 0)
                    msgbox('Circumference must be a positive number','Error','error');                    
                    throw(MException());
            end
            
            calibration_dir = strcat(full_path,"\\" , calibration_dir);
            
            RadiusErrorPercentage = app.RadiusErrorPercentageEditField.Value;
            SphereRadius = app.SpherecircumferencecmEditField.Value/(100*2*pi);
            radius_error = RadiusErrorPercentage * SphereRadius;
            
            % first checks if the calibration was already made, 
            % and didn't specifically
            % asked for new calibration
            
            serial_numbers = find_serial_numbers(calibration_dir);
            if(isempty(serial_numbers))
                msgbox("Invalid calibration directory. " + ...
                    "Couldn't find serial Numbers",'Error','error');
                throw(MException());              
            end
            print_text(app, "Running calibration.");
            try
                [~, ~, calibration_valid, calibration_mse,...
                ransac_mme, ransac_radius_errors] = ...
                calibrate_depth_cameras(app, calibration_dir, serial_numbers, ...
                SphereRadius, radius_error, ...
                'MaxNumTrials', app.MaxNumTrialsEditField.Value, ...
                'numOfTries', app.numOfTriesEditField.Value,...
                'MaxDistance', app.MaxDistanceEditField.Value,...
                'DrawCentroids', app.DrawCentroidsCheckBox.Value);
            catch
                print_text(app, "Running was stopped by the user.")
                throw(MException());
            end
             for i= 1:length(serial_numbers)
                print_text(app, strcat("Camera #" ,num2str(i), " MSAC results - "));
                print_text(app, strcat("Average Mean Error (in meters) - " , ...
                 num2str(ransac_mme(i))));
                print_text(app, strcat("Average of Absolute Radius Error precentage - " , ...
                 num2str(100 * ransac_radius_errors(i)/SphereRadius), "%"));
             end
                
             for i= 1:length(calibration_valid)
                 print_text(app, strcat("Calibration of camera #", num2str(i), ...
                  " and camera #", num2str(i+1), " results - "));
                 print_text(app, strcat(num2str(calibration_valid(i)), ...
                  "% of the spheres were valid"));
                 print_text(app, strcat("Calibration Mean Square Error " + ...
                  "(in meters) - ", ...
                  num2str(calibration_mse(i))));
             end
                   
        end
        
        function results = run_alignment(app)
            
            global full_path;
            calibration_dir = app.AvailableCalibrationFoldersDropDown.Value;
            captures_folders = app.AvailableCapturesFoldersListBox.Value;
            
            if(isempty(calibration_dir))
                msgbox('Please select a calibration directory','Error','error');
                throw(MException());              
            end
            
            if(isempty(captures_folders))
                msgbox('Please select at least one captures directory', ...
                    'Error','error');
                throw(MException());              
            end
            
            calibration_dir = strcat(full_path, '\\', calibration_dir);
            R_file = strcat(calibration_dir,'\\R.mat');
            t_file = strcat(calibration_dir,'\\t.mat');
            
            if(isfile(R_file) && isfile(t_file))
                print_text(app, "Loading R and t.");
                % load R and t
                R = load(R_file);
                R = R.R;
                t = load(t_file);
                t = t.t; 
            else
                msgbox('No transformation files were found.', ...
                    'Error','error');
                throw(MException());
            end
            
            serial_numbers = find_serial_numbers(calibration_dir);
            if(isempty(serial_numbers))
                msgbox("Invalid calibration directory. " + ...
                    "Couldn't find serial numbers",'Error','error');
                throw(MException());              
            end
            
            for k=1:length(captures_folders)
                captures_dir = convertCharsToStrings(captures_folders{k});
                captures_dir = strcat(full_path,"\\" , captures_dir);
            
                if(app.AddNormalsCheckBox.Value)
                    print_text(app, captures_folders{k} + " - " + ...
                        "Adding normals to point clouds.");
                    add_normals_to_point_clouds(captures_dir, ...
                        app.KnormalsEditField.Value);
                end
                print_text(app, captures_folders{k} + " - " + ...
                        "Aligning point clouds.")
                try
                    align_point_clouds(app, captures_dir,...
                    serial_numbers, R,t, ...
                    'TrimPointCloud',app.TrimPointCloudCheckBox.Value,...
                    'minDistance',app.MinDistanceEditField.Value,...
                    'gridStep',app.GridStepEditField.Value,...
                    'SameColor',false); 
                catch
                    print_text(app, "Running was stopped by the user.")
                    throw(MException());
                end
            end
            
        end
        
        function results = run_denoising_before_ICP(app)
            global full_path;
            captures_folders = app.AvailableCapturesFoldersListBox.Value;
            if(isempty(captures_folders))
                msgbox('Please select at least one captures directory', ...
                    'Error','error');
                throw(MException());              
            end
            
            for k=1:length(captures_folders)
            
                print_text(app, captures_folders{k} + " - " + ...
                        "Denoising aligned point clouds.");
                
                if(check_if_stop_running(app))
                    print_text(app, "Running was stopped by the user.")
                    throw(MException());
                end
                
                captures_dir = convertCharsToStrings(captures_folders{k});
                captures_dir = strcat(full_path,"\\" , captures_dir);
                
                print_text(app, captures_folders{k} + " - " + ...
                        "Loading aligned point clouds.");
                
                % load already aligned point clouds
                Files=dir(captures_dir);
                for j = 3:length(Files)
                    if(check_if_stop_running(app))
                        print_text(app, "Running was stopped by the user.")
                        throw(MException());
                    end
                    fileName=Files(j).name;
                    if(regexp(fileName,'[a-z].ply') == 1) % the name is letter with .ply
                        ptCloud = pcread(strcat(captures_dir,'\\',fileName));
                        ptCloud = pcneighborsfilter(ptCloud, ...
                        app.NeighborsFilterRadiusEditField.Value, ...
                        app.NeighborsFilterNoisePercentageEditField.Value);
                    
                        if(check_if_stop_running(app))
                            print_text(app, "Running was stopped by the user.")
                            throw(MException());
                        end
                        ptCloud = pc_guided_filter(ptCloud, ...
                            app.GuidedFilterRadiusEditField.Value, ...
                            app.GuidedFilterRegularizationEditField.Value);
                        pcwrite(ptCloud, ...
                        strcat(captures_dir,'\\',...
                        strrep(fileName, ".", "_denoised.")));        
                    end
                end
            
            end
            
        end
        
        function results = run_registration(app)
            global selected_point_clouds;
            global full_path;
            METERS_TO_MM = false;
            MM_TO_METERS = true;
            captures_folders = app.AvailableCapturesFoldersListBox.Value;
            if(isempty(captures_folders))
                msgbox('Please select at least one captures directory', ...
                    'Error','error');
                throw(MException());              
            end
            
            for k=1:length(captures_folders)
                if(check_if_stop_running(app))
                    print_text(app, "Running was stopped by the user.")
                    throw(MException());
                end
                captures_dir = convertCharsToStrings(captures_folders{k});
                captures_dir = strcat(full_path,"\\" , captures_dir);
                finalDir = strcat(captures_dir, "\\final");
                finalDir = convertStringsToChars(finalDir);
                registered_pc_path = [finalDir,'\\registered.ply'];
                final_pc_path = [finalDir,'\\final.ply'];
                ptClouds = {};
                denoised_PtClouds = {};
                
                Files=dir(captures_dir);
                print_text(app, captures_folders{k} + " - " + ...
                        "Loading point clouds for registration.");
                for j = 3:length(Files)
                    if(check_if_stop_running(app))
                        print_text(app, "Running was stopped by the user.")
                        throw(MException());
                    end
                    fileName=Files(j).name;
                    if(regexp(fileName,'[a-z].ply') == 1)
                        ptCloud = pcread(strcat(captures_dir,'\\',fileName));
                        ptClouds = [ptClouds(:)' {ptCloud}];            
                    elseif(regexp(fileName,'[a-z]_denoised.ply') == 1)
                        denoised_ptCloud = ...
                            pcread(strcat(captures_dir,'\\',fileName));
                        denoised_PtClouds = [denoised_PtClouds(:)' ...
                            {denoised_ptCloud}];            
                    end
                end
                
                if(~isempty(ptClouds))
                    if(app.SelectPointCloudsCheckBox.Value)
                        print_text(app, captures_folders{k} + " - " + ...
                            "Please select aligned point clouds " + ...
                            "for registration. Press 'Select' when done. " + ...
                            "You can skip this by selecting no point cloud.");
                        selected_point_clouds = {};
                        show_point_clouds(app, ptClouds);  
                        uiwait;
                    else
                        selected_point_clouds = ptClouds;
                    end
                    if(~isempty(selected_point_clouds))
                        print_text(app, captures_folders{k} + " - " + ...
                        "Registering selected aligned point clouds.");
                        
                        try
                            [registered_pc, reg_errors] = ...
                            register_point_clouds_threshold(app, ...
                            selected_point_clouds, ...
                            app.MinErrorThresholdEditField.Value, ...
                            app.MaxErrorThresholdEditField.Value, ...
                            app.JumpErrorThresholdEditField.Value, ...
                            min(app.MinSuccessfulRegisterationsEditField.Value, ...
                            length(selected_point_clouds)),...
                            app.GridStepEditField_2.Value, ...
                            app.InliersRatioEditField.Value);
                        catch
                            print_text(app, "Running was stopped by the user.")
                            throw(MException());
                        end
                    
                        print_reg_results(app, reg_errors);
                        
                        create_final_dir(app, captures_dir);
                        
                        print_text(app, captures_folders{k} + " - " + ...
                        "Scaling and saving 'registered' point cloud.");
                        
                        pcwrite(scale_pc(registered_pc, METERS_TO_MM),...
                            registered_pc_path);
                    end
                end
                if(~isempty(denoised_PtClouds))
                    if(app.SelectPointCloudsCheckBox.Value)
                        print_text(app, captures_folders{k} + " - " + ...
                            "Please select denoised point clouds " + ...
                            "for registration. Press 'Select' when done. "+ ...
                            "You can skip this by selecting no point cloud.");
                        selected_point_clouds = {};
                        show_point_clouds(app, denoised_PtClouds);   
                        uiwait;
                    else
                        selected_point_clouds = denoised_PtClouds;
                    end
                    if(~isempty(selected_point_clouds))
                        print_text(app, captures_folders{k} + " - " + ...
                        "Registering selected denoised point clouds.");
                        
                        try
                            [final_pc, reg_errors] = ...
                            register_point_clouds_threshold(app, ...
                            selected_point_clouds, ...
                            app.MinErrorThresholdEditField.Value, ...
                            app.MaxErrorThresholdEditField.Value, ...
                            app.JumpErrorThresholdEditField.Value, ...
                            min(app.MinSuccessfulRegisterationsEditField.Value, ...
                            length(selected_point_clouds)),...
                            app.GridStepEditField_2.Value, ...
                            app.InliersRatioEditField.Value);
                        catch
                            print_text(app, "Running was stopped by the user.")
                            throw(MException());
                        end
                    
                        print_reg_results(app, reg_errors);
                        
                        create_final_dir(app, captures_dir);
                        
                        print_text(app, captures_folders{k} + " - " + ...
                        "Scaling and saving 'final' point cloud.");
                        
                        pcwrite(scale_pc(final_pc, METERS_TO_MM),...
                            final_pc_path);
                    end
                end
                
            end
            
        end
        
        
        function results = run_denoising_after_ICP(app)
            global full_path;
            METERS_TO_MM = false;
            MM_TO_METERS = true;
            captures_folders = app.AvailableCapturesFoldersListBox.Value;
            if(isempty(captures_folders))
                msgbox('Please select at least one captures directory', ...
                    'Error','error');
                throw(MException());              
            end
            
            for k=1:length(captures_folders)
                if(check_if_stop_running(app))
                    print_text(app, "Running was stopped by the user.")
                    throw(MException());
                end
                captures_dir = convertCharsToStrings(captures_folders{k});
                captures_dir = strcat(full_path,"\\" , captures_dir);
                finalDir = strcat(captures_dir, "\\final");
                finalDir = convertStringsToChars(finalDir);
                registered_pc_path = [finalDir,'\\registered.ply'];
                final_pc_path = [finalDir,'\\final.ply'];
                registered_denoised_pc_path = [finalDir,'\\registered_denoised.ply'];
                final_denoised_pc_path = [finalDir,'\\final_denoised.ply'];
                
                if(isfile(registered_pc_path))
                    print_text(app, captures_folders{k} + " - " + ...
                        "Denoising 'registered' point cloud.");
                    registered_pc = scale_pc(pcread(registered_pc_path), ...
                        MM_TO_METERS);
                    
                    try
                        registered_denoised_pc = final_denoising(app, ...
                            registered_pc, ...
                            app.NeighborsFilterRadiusEditField.Value,...
                            app.NeighborsFilterNoisePercentageEditField.Value,...
                            app.GuidedFilterRadiusEditField.Value,...
                            app.GuidedFilterRegularizationEditField.Value,...
                            app.BoundryFilterRadiusEditField.Value,...
                            app.BoundryFilterThresholdEditField.Value);
                        pcwrite(scale_pc(registered_denoised_pc, METERS_TO_MM),...
                            registered_denoised_pc_path);
                    catch
                        print_text(app, "Running was stopped by the user.")
                        throw(MException());                        
                    end
                
                end
                
                if(isfile(final_pc_path))
                    print_text(app, captures_folders{k} + " - " + ...
                        "Denoising 'final' point cloud.");
                    final_pc = scale_pc(pcread(final_pc_path), ...
                        MM_TO_METERS);
                    
                    try
                        final_denoised_pc = final_denoising(app, ...
                            final_pc, ...
                            app.NeighborsFilterRadiusEditField.Value,...
                            app.NeighborsFilterNoisePercentageEditField.Value,...
                            app.GuidedFilterRadiusEditField.Value,...
                            app.GuidedFilterRegularizationEditField.Value,...
                            app.BoundryFilterRadiusEditField.Value,...
                            app.BoundryFilterThresholdEditField.Value);
                        pcwrite(scale_pc(final_denoised_pc, METERS_TO_MM),...
                            final_denoised_pc_path);
                    catch
                        print_text(app, "Running was stopped by the user.")
                        throw(MException());                        
                    end
                end
            end
        end
        
        function results = run_reconstruction(app)
            global full_path;
            captures_folders = app.AvailableCapturesFoldersListBox.Value;
            if(isempty(captures_folders))
                msgbox('Please select at least one captures directory', ...
                    'Error','error');
                throw(MException());              
            end
            
            for k=1:length(captures_folders)
                if(check_if_stop_running(app))
                    print_text(app, "Running was stopped by the user.")
                    throw(MException());
                end
                captures_dir = convertCharsToStrings(captures_folders{k});
                captures_dir = strcat(full_path,"\\" , captures_dir);
                finalDir = strcat(captures_dir, "\\final");
                finalDir = convertStringsToChars(finalDir);
                registered_pc_path = [finalDir,'\\registered.ply'];
                final_pc_path = [finalDir,'\\final.ply'];
                registered_denoised_pc_path = [finalDir,'\\registered_denoised.ply'];
                final_denoised_pc_path = [finalDir,'\\final_denoised.ply'];
                registered_recon_path = [finalDir,'\\registered_recon.ply'];
                final_recon_path = [finalDir,'\\final_recon.ply'];
                registered_denoised_recon_path = [finalDir,'\\registered_denoised_recon.ply'];
                final_denoised_recon_path = [finalDir,'\\final_denoised_recon.ply'];
                
                if(isfile(registered_pc_path))
                    print_text(app, captures_folders{k} + " - " + ...
                        "Surface reconstructing for 'registered' " + ...
                        "point clouds");
                    poisson_surface_reconstruction(...
                    registered_pc_path, ...
                    registered_recon_path, ...
                    app.PoissonDepthEditField.Value, ...
                    app.PoissonSamplesPerNodeEditField.Value);
                end
                if(isfile(final_pc_path))
                    print_text(app, captures_folders{k} + " - " + ...
                        "Surface reconstructing for 'final' " + ...
                        "point clouds");
                    poisson_surface_reconstruction(...
                    final_pc_path, ...
                    final_recon_path, ...
                    app.PoissonDepthEditField.Value, ...
                    app.PoissonSamplesPerNodeEditField.Value);
                end
                if(isfile(registered_denoised_pc_path))
                    print_text(app, captures_folders{k} + " - " + ...
                        "Surface reconstructing for 'registered_denoised' " + ...
                        "point clouds");
                    poisson_surface_reconstruction(...
                    registered_denoised_pc_path, ...
                    registered_denoised_recon_path, ...
                    app.PoissonDepthEditField.Value, ...
                    app.PoissonSamplesPerNodeEditField.Value);
                end
                if(isfile(final_denoised_pc_path))
                    print_text(app, captures_folders{k} + " - " + ...
                        "Surface reconstructing for 'final_denoised' " + ...
                        "point clouds");
                    poisson_surface_reconstruction(...
                    final_denoised_pc_path, ...
                    final_denoised_recon_path, ...
                    app.PoissonDepthEditField.Value, ...
                    app.PoissonSamplesPerNodeEditField.Value);
                end
            end
        end
                
        function results = show_point_clouds(app, ptClouds)
            global point_clouds_to_show;
            point_clouds_to_show = ptClouds;
            app.PointCloudsListListBox.Enable = true;
            app.SelectAllButton.Enable = true;
            app.SelectPointCloudsButton.Enable = true;
            app.PointCloudsListListBox.Items = ...
                cellstr(num2str((1:length(ptClouds)).'));
            app.RunningLamp.Color = 'red';                         
            drawnow;
        end
        
        function [final_pc, reg_errors] = register_point_clouds_threshold(app, ...
            ptClouds, ...
            min_error_threshold,max_error_threshold,jump_error_threshold, ...
            min_num_of_registeration, gridStep, InlierRatio)
            if(length(ptClouds) == 1)
                final_pc = ptClouds(1);
                reg_errors = [];
                return;
            end
            error_threshold = min_error_threshold;
            while(error_threshold < max_error_threshold + jump_error_threshold)
                if(check_if_stop_running(app))
                    throw(MException());
                end
                [final_pc,reg_errors] = chain_registration(app, ptClouds, gridStep, ...
                    error_threshold, InlierRatio);
                num_of_registeration = sum(reg_errors>0);
                if(num_of_registeration < min_num_of_registeration)
                        print_text(app, "Raising registration error threshold.");
                        error_threshold = error_threshold + jump_error_threshold;
        
                else
                    break;
                end
            end
        end
        
        function print_reg_results(app, reg_errors)
           for i = 1:length(reg_errors)
            print_text(app, strcat("Root Mean Square Error - ", num2str(reg_errors(i))));       
            end 
        end
        
        function denoised_ptCloud = final_denoising(app, ptCloud, ...
            RadiusFilterRadius, RadiusFilterNoisePercentage, ...
            GuidedFilterRadius, GuidedFilterRegularization,...
            BoundryFilterRadius, BoundryFilterThreshold)

            denoised_ptCloud = pcneighborsfilter(ptCloud, ...
                RadiusFilterRadius, RadiusFilterNoisePercentage);
            if(check_if_stop_running(app))
                throw(MException());
            end
            denoised_ptCloud = pc_guided_filter(denoised_ptCloud, ...
                GuidedFilterRadius, GuidedFilterRegularization);
            if(check_if_stop_running(app))
                throw(MException());
            end
            denoised_ptCloud = pcneighborsfilter(denoised_ptCloud, ...
                RadiusFilterRadius, RadiusFilterNoisePercentage);
            if(check_if_stop_running(app))
                throw(MException());
            end
            denoised_ptCloud = pc_find_boundry_points(denoised_ptCloud, ...
                BoundryFilterRadius, BoundryFilterThreshold);
        end
        
        function create_final_dir(app, calibration_dir)
            finalDir = strcat(calibration_dir, "\\final");
            finalDir = convertStringsToChars(finalDir);
            if ~exist(finalDir, 'dir')
               mkdir(finalDir)
               print_text(app, "Creating final directory.");
            end
        end
        
        function stop = check_if_stop_running(app)
            stop = false;
            drawnow;
            if(app.STOPButton.Value == true)
               app.STOPButton.Value = false;
               stop = true;
            end
            drawnow;
        end
        
        function results = print_text(app, text)
            app.PrintingOutputTextArea.Value = ...
            [app.PrintingOutputTextArea.Value(:)' ...
            {convertStringsToChars(text)}];  
            drawnow;
        end
    end
    
    methods (Access = private)
        
        function results = enable_running_buttons(app, enable)
            app.RunCalibrationButton.Enable = enable;
            app.RunAlignmentButton.Enable = enable;
            app.RunDenoisingBeforeButton.Enable = enable;
            app.RunDenoisingAfterButton.Enable = enable;
            app.RunRegistrationButton.Enable = enable;
            app.RunReconstructionButton.Enable = enable;
            app.RunAlgorithmButton.Enable = enable;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TabGroup.Visible = false;
            global pc_figure;
            global pc_ax;
            set_defaults(app);
            
            
            screensize = get( groot, 'Screensize' );
            x_size = screensize(3);
            y_size = screensize(4);
            app.UIFigure.Position=[1,round(y_size/20), ...
                round(x_size*2/3), round(y_size*18/20)];
            pc_figure = figure('Name','Show Point Clouds');
            pc_figure.OuterPosition = [round(x_size*2/3), 0, round(x_size/3), y_size];
            pc_ax= gca;
            view(pc_ax,3);           
            
        end

        % Value changed function: SelectDirectoryButton
        function SelectDirectoryButtonValueChanged(app, event)
            global full_path;
            temp_path = uigetdir;
            if(~isequal(temp_path,0))
                full_path = temp_path;
                
                if(isempty(full_path))
                    msgbox('Please select a non-empty directory','Error','error');
                else
                    app.AvailableCalibrationFoldersDropDown.Items = {};
                    app.AvailableCapturesFoldersListBox.Items = {};
                    split_path = split(full_path,'\');
                    app.ContainingDirectoryNameEditField.Value = split_path{end};
                    files = dir(full_path);
                    dirFlags = [files.isdir];
                    subFolders = files(dirFlags);
                    for k = 3 : length(subFolders)
                        if(contains(subFolders(k).name, 'calibration'))
                            app.AvailableCalibrationFoldersDropDown.Items = ...
                            [app.AvailableCalibrationFoldersDropDown.Items(:)' ...
                            {subFolders(k).name}];
                        else
                            app.AvailableCapturesFoldersListBox.Items = ...
                            [app.AvailableCapturesFoldersListBox.Items(:)' ...
                            {subFolders(k).name}];
                        end
                    end
                end
            end
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Callback function
        function ExpertModeButtonValueChanged(app, event)
            value = app.ExpertModeButton.Value;
            app.TabGroup.Visible = value;                 
        end

        % Button pushed function: RunAlgorithmButton
        function RunAlgorithmButtonPushed(app, event)
            global full_path;            
            
            if(~isequal(full_path,0)) 
                app.RunningLamp.Color = 'green'; 
                enable_running_buttons(app, false);
                try
                    if(app.RunNewCalibrationCheckBox.Value)
                        run_calibration(app);
                    end
                    if(app.NewAlignmentCheckBox.Value)
                        run_alignment(app);
                    end
                    if(app.CleanBeforeRegisterationCheckBox.Value)
                       run_denoising_before_ICP(app); 
                    end
                    if(app.NewRegistrationCheckBox.Value)
                        run_registration(app); 
                    end
                    if(app.CleanAfterRegisterationCheckBox.Value)
                        run_denoising_after_ICP(app);
                    end
                    if(app.NewReconstructionCheckBox.Value)
                        run_reconstruction(app);
                    end
                catch
                end
                
            else
                msgbox('Please select a directory','Error','error');  
            end
            
            enable_running_buttons(app, true);
            app.RunningLamp.Color = 'red';                         
            drawnow;
        end

        % Callback function
        function OpenPlaneCutGUIButtonPushed(app, event)
            run('pccut_gui.mlapp');
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModeButtonGroup.SelectedObject;
            selectedButton = convertCharsToStrings(selectedButton.Text);
            if(selectedButton == "Regular")
                
                app.TabGroup.Visible = false;                
                
            elseif(selectedButton == "Advanced")
                app.TabGroup.Visible = true;
                app.RadiusErrorPercentageEditField.Enable = false;
                app.MaxNumTrialsEditField.Enable = false;
                app.numOfTriesEditField.Enable = false;
                app.RunNewCalibrationCheckBox.Enable = true;
                app.DrawCentroidsCheckBox.Enable = false;
                app.MaxDistanceEditField.Enable = false;
                
                app.GridStepEditField.Enable = false;
                app.NewAlignmentCheckBox.Enable = true;
                app.AddNormalsCheckBox.Enable = false;
                app.KnormalsEditField.Enable = false;
                app.TrimPointCloudCheckBox.Enable = false;
                app.MinDistanceEditField.Enable = false;
                
                app.NeighborsFilterRadiusEditField.Enable = false;
                app.NeighborsFilterNoisePercentageEditField.Enable = false;
                app.CleanBeforeRegisterationCheckBox.Enable = true;
                app.CleanAfterRegisterationCheckBox.Enable = true;
                app.GuidedFilterRadiusEditField.Enable = false;
                app.GuidedFilterRegularizationEditField.Enable = false;
                app.BoundryFilterRadiusEditField.Enable = false;
                app.BoundryFilterThresholdEditField.Enable = false;
                
                app.MinErrorThresholdEditField.Enable = false;
                app.NewRegistrationCheckBox.Enable = true;
                app.GridStepEditField_2.Enable = false;
                app.MaxErrorThresholdEditField.Enable = false;
                app.JumpErrorThresholdEditField.Enable = false;
                app.MinSuccessfulRegisterationsEditField.Enable = false;
                app.InliersRatioEditField.Enable = false;
                
                app.PoissonDepthEditField.Enable = false;
                app.PoissonSamplesPerNodeEditField.Enable = false;
                
            else %Expert
                app.TabGroup.Visible = true;
                app.RadiusErrorPercentageEditField.Enable = true;
                app.MaxNumTrialsEditField.Enable = true;
                app.numOfTriesEditField.Enable = true;
                app.RunNewCalibrationCheckBox.Enable = true;
                app.MaxDistanceEditField.Enable = true;
                app.DrawCentroidsCheckBox.Enable = true;
                
                app.GridStepEditField.Enable = true;
                app.NewAlignmentCheckBox.Enable = true;
                app.AddNormalsCheckBox.Enable = true;
                app.KnormalsEditField.Enable = true;
                app.TrimPointCloudCheckBox.Enable = true;
                app.MinDistanceEditField.Enable = true;
                
                app.NeighborsFilterRadiusEditField.Enable = true;
                app.NeighborsFilterNoisePercentageEditField.Enable = true;
                app.CleanBeforeRegisterationCheckBox.Enable = true;
                app.CleanAfterRegisterationCheckBox.Enable = true;
                app.GuidedFilterRadiusEditField.Enable = true;
                app.GuidedFilterRegularizationEditField.Enable = true;
                app.BoundryFilterRadiusEditField.Enable = true;
                app.BoundryFilterThresholdEditField.Enable = true;
                
                app.MinErrorThresholdEditField.Enable = true;
                app.NewRegistrationCheckBox.Enable = true;
                app.GridStepEditField_2.Enable = true;
                app.MaxErrorThresholdEditField.Enable = true;
                app.JumpErrorThresholdEditField.Enable = true;
                app.MinSuccessfulRegisterationsEditField.Enable = true;
                app.InliersRatioEditField.Enable = true;
                
                app.PoissonDepthEditField.Enable = true;
                app.PoissonSamplesPerNodeEditField.Enable = true;               
                
            end
        end

        % Button pushed function: SetAllValuestoDefaultButton
        function SetAllValuestoDefaultButtonPushed(app, event)
           set_defaults(app); 
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.PrintingOutputTextArea.Value = {''};
            drawnow;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            h = msgbox('Closing app...');
            pause(1);
            global pc_figure;
            delete(pc_figure);
            delete(h);
            delete(app)    
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if(strcmp(key,'f1'))
                help(app);
            end
        end

        % Button pushed function: HELPButton
        function HELPButtonPushed(app, event)
            help(app);
        end

        % Button pushed function: CalibrationParametersButton
        function CalibrationParametersButtonPushed(app, event)
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            
            help_text = {"\fontsize{12}Calibration Parameters - ",...
                        "* Run New Calibration - run a new calibration and " + ...
                        "run over the results of the previous calibration." ,...
                        "* Radius Error Percentage - an allowed error (in percentages) " + ...
                        "in the radius of the sphere that is found in the point cloud. " + ...
                        "Must be a value between 0 and 1. The smaller it is, the more " + ...
                        "likely that the found sphere is a valid one, but less spheres " + ...
                        "will be detected as valid spheres." , ...
                        "* MaxNumTrials - the maximum number of iterations of " + ...
                        "MSAC for finding the sphere. must be greater than 0. " + ...
                        "Smaller values will result shorter runtime, but " + ...
                        "the calibration results would be less accurate." , ...
                        "* numOfTries - number of tries for finding the sphere. " + ...
                        "After searching for the sphere numerous times, only the " + ...
                        "spheres with a valid radius are taken to acount, " + ...
                        "and the location of the sphere's centroid is " + ...
                        "the mean the valid found spheres. " + ...
                        "Smaller values will result shorter runtime, but " + ...
                        "the calibration results would be less accurate." , ...
                        "* MaxDistance (meters) - the maximum distance between an " + ...
                        "point to the sphere. " + ...
                        "Smaller values will result long runtime, but " + ...
                        "the calibration results would be more accurate." , ...
                        "* Draw Centroids - draw the centroids of the spheres " + ...
                        "before and after the transformation."};
            msgbox(help_text, 'HELP Calibration','help',CreateStruct);
        end

        % Button pushed function: AlignmentParametersButton
        function AlignmentParametersButtonPushed(app, event)
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            
            help_text = {"\fontsize{12}Alignment Parameters - ",...
                        "* Run New Alignment - run a new alignment and " + ...
                        "run over the results of the previous alignment." ,...
                        "* Add Normals - replace the normals of the captures " + ...
                        "point clouds with new estimated normals." , ...
                        "* K-normals - the number of points used for " + ...
                        "local plane fitting. K must be greater or " + ...
                        "equal to 3. Smaller values result in " + ...
                        "faster computation at the expense of accuracy." , ...
                        " * Trim Point Cloud - keep only the biggest " + ...
                        "cluster in the point cloud." , ...
                        "* MinDistance (meters) - minimum distance between points " + ...
                        "from different clusters. Values that are " + ...
                        "too small might delete wanted points from the " + ...
                        "point cloud, and values that are too big " + ...
                        "might keep unwanted points." , ...
                        "* GridStep (meters) - points within a 3-D box in the " + ...
                        "size of gridStep are merged to a single point. " + ...
                        "Smaller values will produce a less dense point cloud."};
            msgbox(help_text, 'HELP Alignment','help',CreateStruct);
        end

        % Button pushed function: DenoisingParametersButton
        function DenoisingParametersButtonPushed(app, event)
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            
            help_text = {"\fontsize{12}Denoising Parameters - ",...
                        "* Clean Before Registration - preform a denoising " + ...
                        "algorithm on the aligned point clouds before registration." , ...
                        "* Clean After Registration - preform a denoising " + ...
                        "algorithm on the aligned point clouds after registration." , ...
                        "* Neighbors Filter : Radius (meters) - " + ...
                        "the radius used for the neighbors filter. "+ ...
                        "Points within this radius are considered " + ...
                        "as neighbors. " + ...
                        "Smaller values result in " + ...
                        "faster computation, and less points will be removed." , ...
                        "* Neighbors Filter : Noise Percentage (percentages) " + ...
                        "- the percentage of points that are considered as " + ...
                        "noise. Smaller values result in less points " + ... 
                        "that will be removed." , ...
                        "* Guided Filter : Radius (meters) - " + ...
                        "the radius used for the guided filter. "+ ...
                        "Smaller values result in " + ...
                        "faster computation, but a less smooth point cloud." , ...
                        "* Guided Filter : Regularization - " + ...
                        "The regulatization term of the guided filter. " + ...
                        "Smaller values result in a less smooth point cloud. " + ...
                        "* Boundry Filter : Radius (meters) - " + ...
                        "the radius used for the boundry filter. "+ ...
                        "Points within this radius are considered " + ...
                        "as neighbors. " + ...
                        "Smaller values result in " + ...
                        "faster computation, and less points will be removed." , ...
                        "* Boundry Filter : Threshold (meters) - " + ...
                        "points that their distance from the " + ...
                        "the centroid of their neighbors is greater " + ...
                        "than the threshold are considered as noise. " + ...
                        "Smaller values result in more points " + ... 
                        "that will be removed." , ...
                        };
            msgbox(help_text, 'HELP Denoising','help',CreateStruct);
        end

        % Button pushed function: RegistrationParametersButton
        function RegistrationParametersButtonPushed(app, event)
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            
            help_text = {"\fontsize{12}Registration Parameters - ",...
                        "* Run New Registration - run a new registration and " + ...
                        "run over the results of the previous registration." ,...
                        "* GridStep (meters) - size of 3-D box for grid filter. " + ...
                        "Smaller values result in faster computation." , ...
                        "* Min Error Threshold (meters) - the minimum " + ...
                        "value of the threshold of the registration " + ...
                        "RMSE. Bigger values result in faster computation " + ...
                        "at the expense of accuracy." , ...
                        "* Max Error Threshold (meters) - the maximum " + ...
                        "value of the threshold of the registration " + ...
                        "RMSE. Smaller values result in faster computation. " + ...
                        "Values that are too big might consider a bad " + ...
                        "registration as a good one, and values that are " + ...
                        "too small might ignore good registrations." , ...
                        "* Jump Error Threshold (meters) - the jump " + ...
                        "value in the threshold of the registration " + ...
                        "RMSE. Bigger values result in faster computation " + ...
                        "at the expense of accuracy." , ...
                        "* Min Successful Registrations - the minimum " + ...
                        "number of good registrations. Smaller values " + ...
                        "result in faster computation at the expense " + ...
                        "of accuracy." , ...
                        "* Inliers Ratio (percentage) - " + ...
                        "percentage of inliers in the registration. " + ...
                        "Must be a number between 0 and 1. " + ...
                        "A pair of matched points is considered " + ...
                        "an inlier if its Euclidean distance falls " + ...
                        "within the percentage set of matching distances. " + ...
                        "Bigger values result in faster computation."
                        };
            msgbox(help_text, 'HELP Registration','help',CreateStruct);
        end

        % Button pushed function: ReconstructionParametersButton
        function ReconstructionParametersButtonPushed(app, event)
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            
            help_text = {"\fontsize{12}Reconstruction Parameters - ",...
                        "* Run New Reconstruction - run a new reconstruction and " + ...
                        "run over the results of the previous reconstruction." ,...
                        "* Poisson Depth - the depth of the poisson surface " + ...
                        "reconstruction. The higher the depth the more detailed the " + ...
                        "mesh. With noisy data you keep vertices in the generated " + ...
                        "mesh that are outliers but the algorithm doesn't " + ...
                        "detect them as such. So a low value provides a " + ...
                        "smoothing effect, but you will lose detail. The " + ...
                        "higher the depth-value the higher is the resulting " + ...
                        "amount of vertices of the generated mesh." , ...
                        "* Samples Per Node - The samples per node parameter" + ...
                        " defines how many points the marching cubes " + ...
                        "algorithm puts into one node of the resulting " + ...
                        "octree. If you have noisy data a high" + ...
                        " sample per node value provides a smoothing with " + ...
                        "loss of detail while a low value keeps the detail " + ...
                        "level high. A high value reduces the resulting " + ...
                        "count of vertices while a low value remains " + ...
                        "them high." , "For more details and examples " + ...
                        "- https://tinyurl.com/poisson-reconstruction"                        
                        };
            msgbox(help_text, 'HELP Reconstruction','help',CreateStruct);
        end

        % Value changed function: STOPButton
        function STOPButtonValueChanged(app, event)
            drawnow;
        end

        % Value changed function: PointCloudsListListBox
        function PointCloudsListListBoxValueChanged(app, event)
            global pc_ax;
            global point_clouds_to_show;
            if(~isempty(app.PointCloudsListListBox.Value))
                last_picked_pointcloud_index = str2num(app.PointCloudsListListBox.Value{end});
                last_picked_pointcloud = point_clouds_to_show{last_picked_pointcloud_index};
                pcshow(last_picked_pointcloud, 'Parent',pc_ax);            
            else
                cla(pc_ax);
            end
       
        end

        % Button pushed function: SelectPointCloudsButton
        function SelectPointCloudsButtonPushed(app, event)
            global point_clouds_to_show;
            global selected_point_clouds;
            selected_point_clouds = {};
            app.PointCloudsListListBox.Enable = true;
            app.SelectAllButton.Enable = true;
            for i = sort(app.PointCloudsListListBox.Value)
                selected_point_clouds = ...
                [selected_point_clouds(:)' ...
                {point_clouds_to_show{str2num(i{1})}}];
            end
            app.PointCloudsListListBox.Items = {};
            app.PointCloudsListListBox.Enable = false;
            app.SelectAllButton.Enable = false;
            app.SelectPointCloudsButton.Enable = false;
            app.RunningLamp.Color = 'green';
            uiresume;
        end

        % Button pushed function: LoadPointCloudButton
        function LoadPointCloudButtonPushed(app, event)
            global pc_ax;
            global full_path;
            [file,path] = uigetfile(strcat(full_path, '\*.ply'), ...
                'Select a Point Cloud');
            if(~isequal(file,0)) 
                h = msgbox('Loading...');    
                ptCloud = pcread(strcat(path,file));
                pcshow(ptCloud, 'Parent', pc_ax);   
                drawnow;
                delete(h);
            end
        end

        % Button pushed function: RunCalibrationButton
        function RunCalibrationButtonPushed(app, event)
            app.RunningLamp.Color = 'green'; 
            enable_running_buttons(app, false);
            try
                run_calibration(app);
            catch
            end
            enable_running_buttons(app, true);
            app.RunningLamp.Color = 'red';                         
            drawnow;
        end

        % Button pushed function: RunAlignmentButton
        function RunAlignmentButtonPushed(app, event)
            app.RunningLamp.Color = 'green'; 
            enable_running_buttons(app, false);
            
            try
                run_alignment(app);
            catch
            end
            enable_running_buttons(app, true);
            app.RunningLamp.Color = 'red';                         
            drawnow;
        end

        % Button pushed function: RunDenoisingBeforeButton
        function RunDenoisingBeforeButtonPushed(app, event)
            app.RunningLamp.Color = 'green'; 
            enable_running_buttons(app, false);
            
            try
                run_denoising_before_ICP(app);
            catch
            end
            enable_running_buttons(app, true);
            app.RunningLamp.Color = 'red';                         
            drawnow;
        end

        % Button pushed function: RunDenoisingAfterButton
        function RunDenoisingAfterButtonPushed(app, event)
            app.RunningLamp.Color = 'green'; 
            enable_running_buttons(app, false);
            
            try
                run_denoising_after_ICP(app);
            catch
            end
            enable_running_buttons(app, true);
            app.RunningLamp.Color = 'red';                         
            drawnow;
        end

        % Button pushed function: RunRegistrationButton
        function RunRegistrationButtonPushed(app, event)
            app.RunningLamp.Color = 'green'; 
            enable_running_buttons(app, false);
            
            try
                run_registration(app);
            catch
            end
            enable_running_buttons(app, true);
            app.RunningLamp.Color = 'red';                         
            drawnow;
        end

        % Button pushed function: RunReconstructionButton
        function RunReconstructionButtonPushed(app, event)
            app.RunningLamp.Color = 'green'; 
            enable_running_buttons(app, false);
            
            try
                run_reconstruction(app);
            catch
            end
            enable_running_buttons(app, true);
            app.RunningLamp.Color = 'red';                         
            drawnow;
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            global pc_ax;
            global point_clouds_to_show;
            app.PointCloudsListListBox.Value = app.PointCloudsListListBox.Items;            
            last_picked_pointcloud_index = str2num(app.PointCloudsListListBox.Value{end});
            last_picked_pointcloud = point_clouds_to_show{last_picked_pointcloud_index};
            pcshow(last_picked_pointcloud, 'Parent',pc_ax);    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.RadiusErrorPercentageEditFieldLabel = uilabel(app.CalibrationTab);
            app.RadiusErrorPercentageEditFieldLabel.Text = 'Radius Error Percentage';
            app.RadiusErrorPercentageEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.RadiusErrorPercentageEditField.Limits = [0 1];
            app.RadiusErrorPercentageEditField.Value = 0.1;
            app.MaxNumTrialsEditFieldLabel = uilabel(app.CalibrationTab);
            app.MaxNumTrialsEditFieldLabel.Text = 'MaxNumTrials';
            app.MaxNumTrialsEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.MaxNumTrialsEditField.Limits = [0 Inf];
            app.MaxNumTrialsEditField.RoundFractionalValues = 'on';
            app.MaxNumTrialsEditField.Value = 1000000;
            app.numOfTriesEditFieldLabel = uilabel(app.CalibrationTab);
            app.numOfTriesEditFieldLabel.Text = 'numOfTries';
            app.numOfTriesEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.numOfTriesEditField.Limits = [0 Inf];
            app.numOfTriesEditField.RoundFractionalValues = 'on';
            app.numOfTriesEditField.Value = 20;
            app.RunNewCalibrationCheckBox = uicheckbox(app.CalibrationTab);
            app.RunNewCalibrationCheckBox.Text = 'Run New Calibration';
            app.DrawCentroidsCheckBox = uicheckbox(app.CalibrationTab);
            app.DrawCentroidsCheckBox.Text = 'Draw Centroids';
            app.MaxDistanceEditFieldLabel = uilabel(app.CalibrationTab);
            app.MaxDistanceEditFieldLabel.Text = 'MaxDistance';
            app.MaxDistanceEditField = uieditfield(app.CalibrationTab, 'numeric');
            app.MaxDistanceEditField.Value = 0.01;
            app.CalibrationParametersButton = uibutton(app.CalibrationTab, 'push');
            app.CalibrationParametersButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationParametersButtonPushed, true);
            app.CalibrationParametersButton.Text = 'Calibration Parameters';
            app.RunCalibrationButton = uibutton(app.CalibrationTab, 'push');
            app.RunCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @RunCalibrationButtonPushed, true);
            app.RunCalibrationButton.Text = 'Run Calibration';
            app.AlignmentTab = uitab(app.TabGroup);
            app.AlignmentTab.Title = 'Alignment';
            app.GridStepEditFieldLabel = uilabel(app.AlignmentTab);
            app.GridStepEditFieldLabel.Text = 'Grid Step';
            app.GridStepEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.GridStepEditField.Limits = [0 1];
            app.GridStepEditField.Value = 0.0005;
            app.NewAlignmentCheckBox = uicheckbox(app.AlignmentTab);
            app.NewAlignmentCheckBox.Text = 'New Alignment';
            app.AddNormalsCheckBox = uicheckbox(app.AlignmentTab);
            app.AddNormalsCheckBox.Text = 'Add Normals';
            app.KnormalsEditFieldLabel = uilabel(app.AlignmentTab);
            app.KnormalsEditFieldLabel.Text = 'K - normals';
            app.KnormalsEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.KnormalsEditField.Limits = [3 Inf];
            app.KnormalsEditField.RoundFractionalValues = 'on';
            app.KnormalsEditField.Value = 3;
            app.MinDistanceEditFieldLabel = uilabel(app.AlignmentTab);
            app.MinDistanceEditFieldLabel.Text = 'MinDistance';
            app.MinDistanceEditField = uieditfield(app.AlignmentTab, 'numeric');
            app.MinDistanceEditField.Limits = [0 1];
            app.MinDistanceEditField.Value = 0.02;
            app.TrimPointCloudCheckBox = uicheckbox(app.AlignmentTab);
            app.TrimPointCloudCheckBox.Text = 'Trim Point Cloud';
            app.TrimPointCloudCheckBox.Value = true;
            app.AlignmentParametersButton = uibutton(app.AlignmentTab, 'push');
            app.AlignmentParametersButton.ButtonPushedFcn = createCallbackFcn(app, @AlignmentParametersButtonPushed, true);
            app.AlignmentParametersButton.Text = 'Alignment Parameters';
            app.RunAlignmentButton = uibutton(app.AlignmentTab, 'push');
            app.RunAlignmentButton.ButtonPushedFcn = createCallbackFcn(app, @RunAlignmentButtonPushed, true);
            app.RunAlignmentButton.Text = 'Run Alignment';
            app.DenoisingTab = uitab(app.TabGroup);
            app.DenoisingTab.Title = 'Denoising';
            app.NeighborsFilterRadiusEditFieldLabel = uilabel(app.DenoisingTab);
            app.NeighborsFilterRadiusEditFieldLabel.Text = 'Neighbors Filter - Radius';
            app.NeighborsFilterRadiusEditField = uieditfield(app.DenoisingTab, 'numeric');
            app.NeighborsFilterRadiusEditField.Limits = [0 1];
            app.NeighborsFilterRadiusEditField.Value = 0.01;
            app.NeighborsFilterNoisePercentageEditFieldLabel = uilabel(app.DenoisingTab);
            app.NeighborsFilterNoisePercentageEditFieldLabel.Text = 'Neighbors Filter - Noise Percentage';
            app.NeighborsFilterNoisePercentageEditField = uieditfield(app.DenoisingTab, 'numeric');
            app.NeighborsFilterNoisePercentageEditField.Limits = [0 1];
            app.NeighborsFilterNoisePercentageEditField.Value = 0.015;
            app.CleanBeforeRegisterationCheckBox = uicheckbox(app.DenoisingTab);
            app.CleanBeforeRegisterationCheckBox.Text = 'Clean Before Registeration';
            app.CleanBeforeRegisterationCheckBox.Value = true;
            app.CleanAfterRegisterationCheckBox = uicheckbox(app.DenoisingTab);
            app.CleanAfterRegisterationCheckBox.Text = 'Clean After Registeration';
            app.CleanAfterRegisterationCheckBox.Value = true;
            app.GuidedFilterRadiusEditFieldLabel = uilabel(app.DenoisingTab);
            app.GuidedFilterRadiusEditFieldLabel.Text = 'Guided Filter - Radius';
            app.GuidedFilterRadiusEditField = uieditfield(app.DenoisingTab, 'numeric');
            app.GuidedFilterRadiusEditField.Limits = [0 Inf];
            app.GuidedFilterRadiusEditField.Value = 0.01;
            app.GuidedFilterRegularizationEditFieldLabel = uilabel(app.DenoisingTab);
            app.GuidedFilterRegularizationEditFieldLabel.Text = 'Guided Filter - Regularization';
            app.GuidedFilterRegularizationEditField = uieditfield(app.DenoisingTab, 'numeric');
            app.GuidedFilterRegularizationEditField.Limits = [0 Inf];
            app.GuidedFilterRegularizationEditField.Value = 0.01;
            app.BoundryFilterRadiusEditFieldLabel = uilabel(app.DenoisingTab);
            app.BoundryFilterRadiusEditFieldLabel.Text = 'Boundry Filter - Radius';
            app.BoundryFilterRadiusEditField = uieditfield(app.DenoisingTab, 'numeric');
            app.BoundryFilterRadiusEditField.Limits = [0 Inf];
            app.BoundryFilterRadiusEditField.Value = 0.01;
            app.BoundryFilterThresholdEditFieldLabel = uilabel(app.DenoisingTab);
            app.BoundryFilterThresholdEditFieldLabel.Text = 'Boundry Filter - Threshold';
            app.BoundryFilterThresholdEditField = uieditfield(app.DenoisingTab, 'numeric');
            app.BoundryFilterThresholdEditField.Limits = [0 Inf];
            app.BoundryFilterThresholdEditField.Value = 0.005;
            app.DenoisingParametersButton = uibutton(app.DenoisingTab, 'push');
            app.DenoisingParametersButton.ButtonPushedFcn = createCallbackFcn(app, @DenoisingParametersButtonPushed, true);
            app.DenoisingParametersButton.Text = {'Denoising'; 'Parameters'};
            app.RunDenoisingBeforeButton = uibutton(app.DenoisingTab, 'push');
            app.RunDenoisingBeforeButton.ButtonPushedFcn = createCallbackFcn(app, @RunDenoisingBeforeButtonPushed, true);
            app.RunDenoisingBeforeButton.Text = {'Run Denoising '; 'Before'};
            app.RunDenoisingAfterButton = uibutton(app.DenoisingTab, 'push');
            app.RunDenoisingAfterButton.ButtonPushedFcn = createCallbackFcn(app, @RunDenoisingAfterButtonPushed, true);
            app.RunDenoisingAfterButton.Text = {'Run Denoising '; 'After'};
            app.RegistrationTab = uitab(app.TabGroup);
            app.RegistrationTab.Title = 'Registration';
            app.MinErrorThresholdEditFieldLabel = uilabel(app.RegistrationTab);
            app.MinErrorThresholdEditFieldLabel.Text = 'Min Error Threshold';
            app.MinErrorThresholdEditField = uieditfield(app.RegistrationTab, 'numeric');
            app.MinErrorThresholdEditField.Limits = [0 1];
            app.MinErrorThresholdEditField.Value = 0.005;
            app.NewRegistrationCheckBox = uicheckbox(app.RegistrationTab);
            app.NewRegistrationCheckBox.Text = 'New Registration';
            app.GridStepEditField_2Label = uilabel(app.RegistrationTab);
            app.GridStepEditField_2Label.Text = 'Grid Step';
            app.GridStepEditField_2 = uieditfield(app.RegistrationTab, 'numeric');
            app.GridStepEditField_2.Limits = [0 1];
            app.GridStepEditField_2.Value = 0.0005;
            app.MaxErrorThresholdEditFieldLabel = uilabel(app.RegistrationTab);
            app.MaxErrorThresholdEditFieldLabel.Text = 'Max Error Threshold';
            app.MaxErrorThresholdEditField = uieditfield(app.RegistrationTab, 'numeric');
            app.MaxErrorThresholdEditField.Limits = [0 1];
            app.MaxErrorThresholdEditField.Value = 0.006;
            app.JumpErrorThresholdEditFieldLabel = uilabel(app.RegistrationTab);
            app.JumpErrorThresholdEditFieldLabel.Text = 'Jump Error Threshold';
            app.JumpErrorThresholdEditField = uieditfield(app.RegistrationTab, 'numeric');
            app.JumpErrorThresholdEditField.Limits = [0 1];
            app.JumpErrorThresholdEditField.Value = 0.0002;
            app.MinSuccessfulRegisterationsEditFieldLabel = uilabel(app.RegistrationTab);
            app.MinSuccessfulRegisterationsEditFieldLabel.Text = 'Min Successful Registerations';
            app.MinSuccessfulRegisterationsEditField = uieditfield(app.RegistrationTab, 'numeric');
            app.MinSuccessfulRegisterationsEditField.Limits = [2 Inf];
            app.MinSuccessfulRegisterationsEditField.RoundFractionalValues = 'on';
            app.MinSuccessfulRegisterationsEditField.Value = 2;
            app.InliersRatioEditFieldLabel = uilabel(app.RegistrationTab);
            app.InliersRatioEditFieldLabel.Text = 'Inliers Ratio';
            app.InliersRatioEditField = uieditfield(app.RegistrationTab, 'numeric');
            app.InliersRatioEditField.Limits = [0 1];
            app.InliersRatioEditField.Value = 0.95;
            app.RegistrationParametersButton = uibutton(app.RegistrationTab, 'push');
            app.RegistrationParametersButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrationParametersButtonPushed, true);
            app.RegistrationParametersButton.Text = 'Registration Parameters';
            app.RunRegistrationButton = uibutton(app.RegistrationTab, 'push');
            app.RunRegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RunRegistrationButtonPushed, true);
            app.RunRegistrationButton.Text = 'Run Registration';
            app.SelectPointCloudsCheckBox = uicheckbox(app.RegistrationTab);
            app.SelectPointCloudsCheckBox.Text = 'Select Point Clouds';
            app.ReconstructionTab = uitab(app.TabGroup);
            app.ReconstructionTab.Title = 'Reconstruction';
            app.PoissonDepthEditFieldLabel = uilabel(app.ReconstructionTab);
            app.PoissonDepthEditFieldLabel.Text = 'Poisson Depth';
            app.PoissonDepthEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.PoissonDepthEditField.Limits = [1 Inf];
            app.PoissonDepthEditField.RoundFractionalValues = 'on';
            app.PoissonDepthEditField.Value = 5;
            app.PoissonSamplesPerNodeEditFieldLabel = uilabel(app.ReconstructionTab);
            app.PoissonSamplesPerNodeEditFieldLabel.Text = 'Poisson Samples Per Node';
            app.PoissonSamplesPerNodeEditField = uieditfield(app.ReconstructionTab, 'numeric');
            app.PoissonSamplesPerNodeEditField.Limits = [1 Inf];
            app.PoissonSamplesPerNodeEditField.RoundFractionalValues = 'on';
            app.PoissonSamplesPerNodeEditField.Value = 20;
            app.NewReconstructionCheckBox = uicheckbox(app.ReconstructionTab);
            app.NewReconstructionCheckBox.Text = 'New Reconstruction';
            app.NewReconstructionCheckBox.Value = true;
            app.ReconstructionParametersButton = uibutton(app.ReconstructionTab, 'push');
            app.ReconstructionParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructionParametersButtonPushed, true);
            app.ReconstructionParametersButton.Text = 'Reconstruction Parameters';
            app.RunReconstructionButton = uibutton(app.ReconstructionTab, 'push');
            app.RunReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @RunReconstructionButtonPushed, true);
            app.RunReconstructionButton.Text = 'Run Reconstruction';
            app.SelectDirectoryButton = uibutton(app.UIFigure, 'state');
            app.SelectDirectoryButton.ValueChangedFcn = createCallbackFcn(app, @SelectDirectoryButtonValueChanged, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.RunAlgorithmButton = uibutton(app.UIFigure, 'push');
            app.RunAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @RunAlgorithmButtonPushed, true);
            app.RunAlgorithmButton.Text = 'Run Algorithm';
            app.SpherecircumferencecmEditFieldLabel = uilabel(app.UIFigure);
            app.SpherecircumferencecmEditFieldLabel.Text = 'Sphere circumference (cm)';
            app.SpherecircumferencecmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpherecircumferencecmEditField.Limits = [0 Inf];
            app.ContainingDirectoryNameEditFieldLabel = uilabel(app.UIFigure);
            app.ContainingDirectoryNameEditFieldLabel.Text = 'Containing Directory Name';
            app.ContainingDirectoryNameEditField = uieditfield(app.UIFigure, 'text');
            app.ContainingDirectoryNameEditField.Enable = 'off';
            app.PrintingOutputTextAreaLabel = uilabel(app.UIFigure);
            app.PrintingOutputTextAreaLabel.Text = 'Printing Output';
            app.PrintingOutputTextArea = uitextarea(app.UIFigure);
            app.PrintingOutputTextArea.Editable = 'off';
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.RegularButton = uitogglebutton(app.ModeButtonGroup);
            app.RegularButton.Text = 'Regular';
            app.RegularButton.Value = true;
            app.AdvancedButton = uitogglebutton(app.ModeButtonGroup);
            app.AdvancedButton.Text = 'Advanced';
            app.ExpertButton = uitogglebutton(app.ModeButtonGroup);
            app.ExpertButton.Text = 'Expert';
            app.SetAllValuestoDefaultButton = uibutton(app.UIFigure, 'push');
            app.SetAllValuestoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetAllValuestoDefaultButtonPushed, true);
            app.SetAllValuestoDefaultButton.Text = 'Set All Values to Default';
            app.AvailableCapturesFoldersListBoxLabel = uilabel(app.UIFigure);
            app.AvailableCapturesFoldersListBoxLabel.Text = {'Available'; 'Captures'; 'Folders'};
            app.AvailableCapturesFoldersListBox = uilistbox(app.UIFigure);
            app.AvailableCapturesFoldersListBox.Items = {};
            app.AvailableCapturesFoldersListBox.Multiselect = 'on';
            app.AvailableCapturesFoldersListBox.Value = {};
            app.RunningLamp = uilamp(app.UIFigure);
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.HELPButton = uibutton(app.UIFigure, 'push');
            app.HELPButton.ButtonPushedFcn = createCallbackFcn(app, @HELPButtonPushed, true);
            app.HELPButton.Text = 'HELP';
            app.STOPButton = uibutton(app.UIFigure, 'state');
            app.STOPButton.ValueChangedFcn = createCallbackFcn(app, @STOPButtonValueChanged, true);
            app.STOPButton.Text = 'STOP';
            app.AvailableCalibrationFoldersDropDownLabel = uilabel(app.UIFigure);
            app.AvailableCalibrationFoldersDropDownLabel.Text = {'Available'; 'Calibration'; 'Folders'};
            app.AvailableCalibrationFoldersDropDown = uidropdown(app.UIFigure);
            app.AvailableCalibrationFoldersDropDown.Items = {};
            app.AvailableCalibrationFoldersDropDown.Value = {};
            app.PointCloudsListLabel = uilabel(app.UIFigure);
            app.PointCloudsListLabel.Text = 'Point Clouds List';
            app.PointCloudsListListBox = uilistbox(app.UIFigure);
            app.PointCloudsListListBox.Items = {};
            app.PointCloudsListListBox.Multiselect = 'on';
            app.PointCloudsListListBox.ValueChangedFcn = createCallbackFcn(app, @PointCloudsListListBoxValueChanged, true);
            app.PointCloudsListListBox.Value = {};
            app.SelectPointCloudsButton = uibutton(app.UIFigure, 'push');
            app.SelectPointCloudsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointCloudsButtonPushed, true);
            app.SelectPointCloudsButton.Text = {'Select '; 'Point '; 'Clouds'};
            app.LoadPointCloudButton = uibutton(app.UIFigure, 'push');
            app.LoadPointCloudButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPointCloudButtonPushed, true);
            app.LoadPointCloudButton.Text = {'Load'; 'Point'; 'Cloud'};
            app.SelectAllButton = uibutton(app.UIFigure, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = {'Select'; 'All'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = run_algorithm_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end